classdef video_end < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        Switch          matlab.ui.control.Switch
        Label_11        matlab.ui.control.Label
        SerialCheckBit  matlab.ui.control.DropDown
        Label_10        matlab.ui.control.Label
        SerialStopBit   matlab.ui.control.DropDown
        Label_9         matlab.ui.control.Label
        SerialBaud      matlab.ui.control.DropDown
        Label_8         matlab.ui.control.Label
        Label_7         matlab.ui.control.Label
        SerialPortNum   matlab.ui.control.DropDown
        Lamp            matlab.ui.control.Lamp
        Label_6         matlab.ui.control.Label
        UIAxes_2        matlab.ui.control.UIAxes
        CenterPanel     matlab.ui.container.Panel
        dsp_loveLabel   matlab.ui.control.Label
        TabGroup_2      matlab.ui.container.TabGroup
        down_2          matlab.ui.container.Tab
        little_silder   matlab.ui.control.Slider
        Label_14        matlab.ui.control.Label
        Button_10       matlab.ui.control.Button
        Tab             matlab.ui.container.Tab
        Button_11       matlab.ui.control.Button
        Slider_mindum   matlab.ui.control.Slider
        Slider_2Label   matlab.ui.control.Label
        Tab_2           matlab.ui.container.Tab
        large_slider    matlab.ui.control.Slider
        Slider_3Label   matlab.ui.control.Label
        Button_12       matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        Button_9        matlab.ui.control.Button
        sub_slider      matlab.ui.control.Slider
        Label_13        matlab.ui.control.Label
        delay_slider    matlab.ui.control.Slider
        Label_12        matlab.ui.control.Label
        Button_8        matlab.ui.control.Button
        Button_7        matlab.ui.control.Button
        TabGroup        matlab.ui.container.TabGroup
        down            matlab.ui.container.Tab
        Button_5        matlab.ui.control.Button
        HzEditField_8   matlab.ui.control.NumericEditField
        HzLabel_8       matlab.ui.control.Label
        down_sampling   matlab.ui.control.NumericEditField
        HzLabel_9       matlab.ui.control.Label
        FIRPanel        matlab.ui.container.Panel
        Button_6        matlab.ui.control.Button
        fir_ctrl        matlab.ui.control.Button
        fir_fc          matlab.ui.control.NumericEditField
        HzLabel_7       matlab.ui.control.Label
        freq_Fs         matlab.ui.control.NumericEditField
        HzLabel_2       matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        Button          matlab.ui.control.Button
        Label           matlab.ui.control.Label
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        file,path; % Description
        SerialObject;%创建串口对象
        player; % Description
        fir_filename;
        sapling_change_filename;
         large_file_name; % Reverb parameters
        mindum_file_name;
    end
    %串口接收数据处理
    methods (Access = private)
        
        % function Deal_serial_data(app)
        %         disp(app.SerialObject.UserData{1});
        %         dec_value = 44100.123; % 24 位整数
        %         ctrl=0x12;
        %         Serial_data_process(app, ctrl, dec_value);
        % 
        % end
        %将数据打包并发送
        function Serial_data_process(app,ctrl,data)
             data_head=[0x55 0xA5];%帧头
             data=floor(data);
             %将数据转为24位二进制数，3个uint8数
             byte1 = bitand(bitshift(data, -16), 127);  % 高位字节
             byte2 = bitand(bitshift(data, -8), 255);   % 中间字节
             byte3 = bitand(data, 255);                 % 低位字节 
             data = uint8([byte1, byte2, byte3]);
             hex_data=[data_head,ctrl,data];
             write(app.SerialObject,hex_data,"uint8");
             writeline(app.SerialObject,'=');%一定要加这句话，'='的十六进制数为3D
             disp(uint8(hex_data));
             %disp(class(hex_data));
        end

        
        function Serial_data_process_FIR(app,data)%发送FIR抽头系数
             data_head=0xAE;%帧头
             ctrl_Fir_data_head=0xAB;
             hex_data=[data_head,ctrl_Fir_data_head,data];
             write(app.SerialObject,hex_data,"uint8");
             writeline(app.SerialObject,'=');%一定要加这句话，'='的十六进制数为3D
             disp(uint8(hex_data));
            
        end
        
    function push_video_out_and_view(app)
        % 选择并读取 .wav 文件
        % [filename, pathname] = uigetfile('*.wav', 'Select a WAV file');
        % if isequal(filename, 0)
        %     disp('User selected Cancel');
        %     return;
        % end
        filepath = fullfile( app.path,app.file);
    
        % 读取 WAV 文件数据和采样频率
        [y, fs] = audioread(filepath);
    
        % 归一化音频数据到 [-1, 1]
        y = y / max(abs(y)); % 归一化音频信号，避免溢出
        y=y(480000:length(y));
        % 使用 audioplayer 创建音频播放对象
        app.player = audioplayer(y, fs);
    
        % 播放音频并实时更新波形和频谱图
        play(app.player);
        T = datetime("now"); % 获取开始播放的时间
        pause(0.1); % 暂停 0.1 秒，等待音频播放开始
    
        while isplaying(app.player)
            T1 = datetime("now");
            time_diff = T1 - T; % 计算时间差（秒）
            s = round(seconds(time_diff) * fs); % 将时间差转换为采样点索引
    
            % 更新时域和频域显示
            if s + 5000 < length(y)
                yp = y(s:s + 5000); % 获取当前片段的数据
                plot(app.UIAxes_2, yp); % 绘制时域图
                set(app.UIAxes_2, 'ylim', [-1 1], 'xlim', [0 5000]);
    
                % 对当前片段做 2048 点 FFT
                y1 = fft(yp, 2048); 
    
                % 计算频率轴，范围从 0 到 fs/2，并将频率进行归一化到 [0, 1] 范围
                f = (0:1023) / 1024; % 归一化频率轴：从 0 到 1
    
                % 对频谱做归一化，防止幅度过大
                magnitude = abs(y1(1:1024)); % 获取前一半频谱
                magnitude = magnitude / max(magnitude); % 归一化幅度
    
                % 绘制频谱图
                plot(app.UIAxes, f, magnitude, 'g'); 
                set(app.UIAxes, 'ylim', [0 1], 'xlim', [0 1]); % 设置x轴范围为0到1
                drawnow; % 刷新图像
            end
        end
    end

        function firFilter = designFIRFilter(~,fs, cutoffFrequencyHz)
            % 计算归一化截止频率：实际截止频率（Hz）除以 Nyquist 频率
            nyquistFrequency = fs / 2;
            normalizedCutoff = cutoffFrequencyHz / nyquistFrequency;
    
            % 设计100阶低通FIR滤波器
            filterOrder = 100;
            firFilter = fir1(filterOrder, normalizedCutoff);
        end
        function fir_out_video(app)
            % 选择并读取 .wav 文件
            filepath = fullfile(app.path, app.file);
        
            % 读取 WAV 文件数据和采样频率
            [y, fs] = audioread(filepath);
        
            % 归一化音频数据到 [-1, 1]
           
        
            % 设计100阶低通FIR滤波器，截止频率设置为所需值，例如1000 Hz
            cutoffFrequencyHz = 12000; % 实际截止频率（Hz）
            firFilter = designFIRFilter(app,fs, cutoffFrequencyHz);
        
            % 使用滤波器处理音频信号
            y_filtered = filter(firFilter, 1, y);
            %pause(10);
             y = y / max(abs(y)); % 归一化音频信号，避免溢出
            % 使用 audioplayer 创建播放滤波后信号的音频对象
            app.player = audioplayer(y_filtered, fs);
        
            % 播放音频并实时更新未滤波信号的波形和频谱图
            play(app.player);
            T = datetime("now"); % 获取开始播放的时间
            pause(0.1); % 暂停 0.1 秒，等待音频播放开始
        
            while isplaying(app.player)
                T1 = datetime("now");
                time_diff = T1 - T; % 计算时间差（秒）
                s = round(seconds(time_diff) * fs); % 将时间差转换为采样点索引
        
                % 更新时域和频域显示
                if s + 5000 < length(y)
                    yp = y(s:s + 5000); % 获取当前片段的数据（未滤波信号）
        
                    % 绘制未滤波信号的时域图
                    plot(app.UIAxes_2, yp);
                    set(app.UIAxes_2, 'ylim', [-1 1], 'xlim', [0 5000]);
        
                    % 对当前片段做 2048 点 FFT，计算频谱
                    y_fft = fft(yp, 2048);
        
                    % 计算频率轴，归一化频率从 0 到 1
                    f = (0:1023) / 1024; % 归一化频率轴：从 0 到 1
        
                    % 对频谱做归一化，防止幅度过大
                    magnitude = abs(y_fft(1:1024));
                    magnitude = magnitude / max(magnitude);
        
                    % 绘制频谱图（未滤波信号的频谱）
                    plot(app.UIAxes, f, magnitude, 'g');
                    set(app.UIAxes, 'ylim', [0 1], 'xlim', [0 1]); % 设置x轴范围为0到1
                    drawnow; % 刷新图像
                end
            end
        end
        
        function playAndPlotAudio(app,filepath_name)
            cd('D:\dsp_app_end\filter_video');
                filepath = fullfile( 'D:\','dsp_app_end','filter_video',filepath_name);
                filepath_temp = fullfile( app.path,app.file);
               % "E:\fpga比赛音频处理\dsp_app_end\filter_video\因为你_filtered.wav"
              % 读取 WAV 文件数据和采样频率
              [y, fs] = audioread(filepath);
              y=y(880000:length(y));
              [y_temp,fs_temp]=audioread(filepath_temp);
                 %"E:\dsp_app_end\filter_video\富士山下_filtered.wav"
              % 归一化音频数据到 [-1, 1]
              y_temp = y_temp / max(abs(y_temp)); % 归一化音频信号，避免溢出
        %"D:\dsp_app_end\filter_video"
              % 使用 audioplayer 创建音频播放对象
              app.player = audioplayer(y, fs);
        
              % 播放音频并实时更新波形和频谱图
              play(app.player);
              T = datetime("now"); % 获取开始播放的时间
              pause(0.1); % 暂停 0.1 秒，等待音频播放开始
        
              while isplaying(app.player)
                  T1 = datetime("now");
                  time_diff = T1 - T; % 计算时间差（秒）
                  s = round(seconds(time_diff) * fs_temp); % 将时间差转换为采样点索引
        
                  % 更新时域和频域显示
                  if s + 5000 < length(y_temp)
                      yp = y_temp(s:s + 5000); % 获取当前片段的数据
                      plot(app.UIAxes_2, yp); % 绘制时域图
                      set(app.UIAxes_2, 'ylim', [-1 1], 'xlim', [0 5000]);
        
                      % 对当前片段做 2048 点 FFT
                      y1 = fft(yp, 2048); 
        
                      % 计算频率轴，范围从 0 到 fs/2，并将频率进行归一化到 [0, 1] 范围
                      f = (0:1023) / 1024; % 归一化频率轴：从 0 到 1
        
                      % 对频谱做归一化，防止幅度过大
                      magnitude = abs(y1(1:1024)); % 获取前一半频谱
                      magnitude = magnitude / max(magnitude); % 归一化幅度
        
                      % 绘制频谱图
                      plot(app.UIAxes, f, magnitude, 'g'); 
                      set(app.UIAxes, 'ylim', [0 1], 'xlim', [0 1]); % 设置x轴范围为0到1
                      drawnow; % 刷新图像
                  end
               end
            
        end


    %     function realtime_gardner_reverb_large(app, x, fs, params)
    %         % 实时 Gardner 混响模型，使用 audioPlayerRecorder 对象
    % 
    %         % 初始化参数
    %         gain = params.gain;
    %         delay = params.delay;
    %         feedback = params.feedback;
    %         fc = params.fc;
    % 
    %         % 确保延迟参数的长度和反馈参数的长度一致
    %         if length(delay) ~= length(feedback)
    %             error('延迟和反馈系数的长度必须一致');
    %         end
    % 
    %         % 创建梳状滤波器的参数
    %         num_combs = length(delay);
    %         max_delay = max(delay); % 最大延迟值
    %         comb_buffers = zeros(num_combs, max_delay + 1); % 增加一个样本的缓冲区长度
    %         comb_out = zeros(size(x));
    % 
    %         % 设计低通滤波器
    %         b = fir1(100, fc / (fs / 2)); % 设计一个 100 阶的 FIR 低通滤波器
    % 
    %         % 创建 audioPlayerRecorder 对象
    %         app.player = audioPlayerRecorder('SampleRate', fs, ...
    %                                          'BufferSize', 512);
    % 
    %         % 分块处理和播放
    %         bufferSize = 512;
    %         idx = 1;
    %         while idx <= length(x)
    %             % 当前音频块
    %             endIdx = min(idx + bufferSize - 1, length(x));
    %             inputChunk = x(idx:endIdx);
    % 
    %             % 处理混响
    %             for i = 1:num_combs
    %                  % 截取缓冲区中的一部分，使其与 inputChunk 匹配
    %                 comb_buffer_part = comb_buffers(i, 1:length(inputChunk));
    %                 comb_buffers(i, :) = [feedback(i) * inputChunk', comb_buffers(i, 1:end-1)];
    % 
    %                 % 将延迟后的信号加入输出
    %                 comb_out(idx:endIdx) = comb_out(idx:endIdx) + comb_buffer_part';
    %             end
    % 
    % 
    %             % 低通滤波处理
    %             outputChunk = filter(b, 1, comb_out(idx:endIdx));
    %             y = gain * (inputChunk + outputChunk');
    % 
    %             % 播放并获取录制结果
    %             play(app.player, y);
    % 
    %             % 移动到下一个音频块
    %             idx = idx + bufferSize;
    %         end
    % 
    %         % 释放播放器资源
    %         release(app.player);
    % end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %串口创建
            com_list=serialportlist("all");%找到可用的串口号
            try
                app.SerialPortNum.Items=com_list;
                app.SerialBaud.Value="115200";
            catch
                %app.portData.portData='null';
                errordlg('没有搜索到任何可以串口');
            end
        end

        % Button pushed function: Button
        function video_in(app, event)
            [app.file,app.path] = uigetfile({'*.wav'},'Select video File'); %选择文件
            if isequal(app.file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(app.path,app.file)])
                app.TextArea.Value=app.file;
                 % 然后可以在这里处理文件，比如读取数据
                
            end
        end

        % Callback function
        function video_out(app, event)
            % Serial_data_process(app,0xa3,0xfe01);%发送音频文件
            % pause(0.001);
            % push_video_out_and_view(app);
        end

        % Callback function
        function fir_data_process(app, event)
            noise_freq_min=app.fir_freq_min.Value;%读取输入的噪声频带范围
            noise_freq_max=app.fir_freq_max.Value;
            sample_Fs=app.freq_Fs.Value;%采样频率
            filter_num=app.fir_fc.Value;%滤波器阶数
            % disp(noise_freq_max);
            % disp(noise_freq_min);
            %disp(class(sample_Fs));
            % disp(filter_num);
            %disp(class(filter_num));
            N=2048;%采样点数
            wc=(noise_freq_max+noise_freq_min)/sample_Fs;%截止频率
            %窗函数选择
            window=hamming(filter_num);
            hn=fir1(filter_num-1,wc,'low',window);
            hn=round(hn*2^15);
            current_dir = pwd; % 当前工作目录
            filename = fullfile(current_dir,'filter_coefficients_shifted.txt');
            writematrix(hn, filename, 'Delimiter', 'tab'); % 以制表符分隔写入
            disp(['移位后的滤波器抽头系数已保存至 ', filename]);
            app.mindum_value.Value='filter_coefficients_shifted.txt';
        end

        % Value changed function: freq_Fs
        function sample_freq(app, event)
            value = app.freq_Fs.Value;
            
        end

        % Value changed function: Switch
        function Switch_value(app, event)
            value = app.Switch.Value;
            if strcmp(value,"打开")
                try
                    app.Lamp.Color="r";
                    app.SerialObject=serialport(...
                    app.SerialPortNum.Value,...%设置端口号
                    str2double(app.SerialBaud.Value),...%设置波特率
                    'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
                    'Parity',app.SerialCheckBit.Value);%设置校验位
                    configureTerminator(app.SerialObject,"LF");%设置串口通信终止位为/LF
                    %设置回调函数readData接受串口返回的数据 
                    configureCallback(app.SerialObject,"terminator",@readSerialData);
                    app.Lamp.Color="g";
                    %调试
                    disp("串口已打开")
                catch
                    errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                    app.Switch.Value='关闭';
                    return
                end
            else
                delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                app.Lamp.Color="r";
            end
            %串口接收数据的回调函数
            function readSerialData(src,event)
                app.SerialObject.UserData=readline(app.SerialObject);
                %msgbox(app.SerialObject.UserData{1},"non-modal");
                Deal_serial_data(app);
            end
        end

        % Callback function
        function FIRButtonPushed(app, event)
            
        end

        % Callback function
        function FIR_(app, event)
            
        end

        % Callback function
        function FIR_para_send(app, event)
            % ctrl_freq_min=0x12;%滤波器过渡带最低频率
            % ctrl_freq_max=0x23;%滤波器过渡带最高频率
            % ctrl_Fs=0x45;%采样频率
            % ctrl_Fir_level=0x56;%滤波器阶数

            %ctrl_video_send=0x89;%发送音频文件
            %ctrl_Fir_data=0x9A;%发送滤波器参数
            %ctrl_Fir_data_head=0xAB;%发送滤波器抽头系数
             %串口发送
            Serial_data_process(app,ctrl_Fs,app.fir_fc.Value);%采样频率
            pause(0.0001);  % 延时 100微秒
            Serial_data_process(app,ctrl_Fs,app.freq_Fs.Value);%采样频率
            pause(0.0001);  % 延时 100微秒
            %Serial_data_process(app,ctrl_Fir_level,app.fir_fc.Value);%滤波器阶数
           % pause(0.0001);  % 延时 100微秒
            
        end

        % Callback function
        function FIR_head_data_send(app, event)
            coefficients = readmatrix('filter_coefficients_shifted.txt');
            %将滤波器抽头系数转换为16位有符号整数
            int16_coefficients = int16(coefficients); 
            %将每个16位有符号整数转换为十六进制表示
            hex_values = dec2hex(int16_coefficients, 4); % 4位十六进制表示
            %初始化uint8数组
            uint8_array = zeros(2, 1, 'uint8');
            
            for i=1:length(hex_values)
                uint8_array(1)=hex2dec(hex_values(i,1:2));%取前两位
                uint8_array(2)=hex2dec(hex_values(i,3:4));%取后两位
                Serial_data_process_FIR(app,[0x00,uint8_array(1),uint8_array(2)]);
               % pause(0.0001);  % 延时 100微秒,可根据仿真确定是否需要延时
            end

        end

        % Callback function
        function filter_level(app, event)
            value = app.fir_fc.Value;
            
        end

        % Value changed function: fir_fc
        function filter_fc(app, event)
            value = app.fir_fc.Value;
            
            %Serial_data_process(app,0xa2,app.freq_Fs.Value);%采样频率,0xa2
             %pause(0.001);  % 延时 100微秒
            Serial_data_process(app,0xa1,app.fir_fc.Value);%截止频率,0xa1
           
        end

        % Button pushed function: fir_ctrl
        function fir_data_ctrl(app, event)
            %fir_out_video(app);
           
           %fprintf(app.fir_filename);
        end

        % Callback function
        function up_sample_fs(app, event)
            up_times=app.HzEditField_6.Value/48000;
             Serial_data_process(app,0xa5,up_times);
        end

        % Button pushed function: Button_5
        function down_sample_fs(app, event)
            
            down_times=48000/app.down_sampling.Value;
             Serial_data_process(app,0xa6,down_times);
             pause(0.01);
             
             playAndPlotAudio(app,app.sapling_change_filename);
        end

        % Button pushed function: Button_6
        function view_out(app, event)
            pause(0.01);
            disp(pwd);
           
           filepath_temp_2 = fullfile( app.path,app.file);
           % "E:\fpga比赛音频处理\dsp_app_end\filter_video\因为你_filtered.wav"
          % 读取 WAV 文件数据和采样频率
          %disp(app.filepath_temp);
          [y, fs] = audioread(filepath_temp);
          [y_temp,fs_temp]=audioread(filepath_temp_2);
             %y=y(480000:length(y));
             y=y(880000:length(y));
    %"E:\dsp_app_end\filter_video\富士山下_filtered.wav"
          % 归一化音频数据到 [-1, 1]
          y_temp = y_temp / max(abs(y_temp)); % 归一化音频信号，避免溢出
    
          % 使用 audioplayer 创建音频播放对象
          app.player = audioplayer(y, fs);
    
          % 播放音频并实时更新波形和频谱图
          play(app.player);
          T = datetime("now"); % 获取开始播放的时间
          pause(0.1); % 暂停 0.1 秒，等待音频播放开始
    
          while isplaying(app.player)
              T1 = datetime("now");
              time_diff = T1 - T; % 计算时间差（秒）
              s = round(seconds(time_diff) * fs_temp); % 将时间差转换为采样点索引
    
              % 更新时域和频域显示
              if s + 5000 < length(y_temp)
                  yp = y_temp(s:s + 5000); % 获取当前片段的数据
                  plot(app.UIAxes_2, yp); % 绘制时域图
                  set(app.UIAxes_2, 'ylim', [-1 1], 'xlim', [0 5000]);
    
                  % 对当前片段做 2048 点 FFT
                  y1 = fft(yp, 2048); 
    
                  % 计算频率轴，范围从 0 到 fs/2，并将频率进行归一化到 [0, 1] 范围
                  f = (0:1023) / 1024; % 归一化频率轴：从 0 到 1
    
                  % 对频谱做归一化，防止幅度过大
                  magnitude = abs(y1(1:1024)); % 获取前一半频谱
                  magnitude = magnitude / max(magnitude); % 归一化幅度
    
                  % 绘制频谱图
                  plot(app.UIAxes, f, magnitude, 'g'); 
                  set(app.UIAxes, 'ylim', [0 1], 'xlim', [0 1]); % 设置x轴范围为0到1
                  drawnow; % 刷新图像
              end
          end
        end

        % Button pushed function: Button_7
        function stop_out_music(app, event)
            stop(app.player);
            Serial_data_process(app,0xa7,0);
             pause(0.01);
        end

        % Button pushed function: Button_8
        function video_test_out(app, event)
            push_video_out_and_view(app);
        end

        % Button pushed function: Button_9
        function mixed_video(app, event)
            pause(0.01);
            
            [x, fs] = audioread(filepath_mixed);
            x=x(680000:length(x),1);
            delay = round(app.delay_slider.Value);
            a = app.sub_slider.Value;
            
            % Generate delayed signal
            z1 = [zeros(delay, 1); x];
            x1 = [x; zeros(delay, 1)];
            y1 = x1 + z1;
            
            % Filter coefficients
            Bz = [0,0,0,0,0,0,0,0,0,0,1];
            Az = [1,0,0,0,0,0,0,0,0,0,-a];
            
            % Apply echo filter
            yy1 = filter(Bz, Az, y1);
            
            % Play the echoed audio
            %sound(yy1, fs);
            app.player = audioplayer(yy1, fs);
            play(app.player);
             T = datetime("now"); % 获取开始播放的时间
            pause(0.1); % 暂停 0.1 秒，等待音频播放开始
            filepath_temp_2 = fullfile( app.path,app.file);
            [y_temp,fs_temp]=audioread(filepath_temp_2);
             y_temp = y_temp / max(abs(y_temp)); % 归一化音频信号，避免溢出
          while isplaying(app.player)
              T1 = datetime("now");
              time_diff = T1 - T; % 计算时间差（秒）
              s = round(seconds(time_diff) * fs_temp); % 将时间差转换为采样点索引
    
              % 更新时域和频域显示
              if s + 5000 < length(y_temp)
                  yp = y_temp(s:s + 5000); % 获取当前片段的数据
                  plot(app.UIAxes_2, yp); % 绘制时域图
                  set(app.UIAxes_2, 'ylim', [-1 1], 'xlim', [0 5000]);
    
                  % 对当前片段做 2048 点 FFT
                  y1 = fft(yp, 2048); 
    
                  % 计算频率轴，范围从 0 到 fs/2，并将频率进行归一化到 [0, 1] 范围
                  f = (0:1023) / 1024; % 归一化频率轴：从 0 到 1
    
                  % 对频谱做归一化，防止幅度过大
                  magnitude = abs(y1(1:1024)); % 获取前一半频谱
                  magnitude = magnitude / max(magnitude); % 归一化幅度
    
                  % 绘制频谱图
                  plot(app.UIAxes, f, magnitude, 'g'); 
                  set(app.UIAxes, 'ylim', [0 1], 'xlim', [0 1]); % 设置x轴范围为0到1
                  drawnow; % 刷新图像
              end
          end
        end

        % Button pushed function: Button_12
        function large_room(app, event)
            pause(0.01);
         
           playAndPlotAudio(app,app.large_file_name);
            % large_mixed = fullfile( 'E:\','dsp_app_end','filter_video',app.fir_filename);
            % [large_x, large_fs] = audioread(large_mixed);
            % app.large.gain = app.large_slider.Value;
            % % 固定的混响参数
            % app.large.delay = [8, 12, 17, 31, 31, 76, 120];
            % app.large.feedback = [0.3, 0.3, 0.25, 0.25, 0.25, 0.25, 0.5];
            % app.large.fc = 2600;
            % 
            % % Apply reverb effect
            %  realtime_gardner_reverb_large(app,large_x, large_fs, app.large);
        end

        % Button pushed function: Button_11
        function mindum_room(app, event)
            pause(0.01);
           
           playAndPlotAudio(app,app.mindum_file_name);
        end

        % Value changing function: Slider_mindum
        function show_mindum(app, event)
            changingValue = event.Value;
            %app.mindum_value.Value=app.Slider_mindum.Value;
        end

        % Button pushed function: Button_10
        function large_room_judge(app, event)
            pause(0.01);
            
           playAndPlotAudio(app,app.mindum_file_name);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {638, 638, 638};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {638, 638};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {499, '1x', 776};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes_2, '时域波形')
            xlabel(app.UIAxes_2, '时间')
            ylabel(app.UIAxes_2, '幅度')
            zlabel(app.UIAxes_2, 'Z')
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '串口配置';
            app.Lamp = uilamp(app.LeftPanel);
            app.SerialPortNum = uidropdown(app.LeftPanel);
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '串口号';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '波特率';
            app.SerialBaud = uidropdown(app.LeftPanel);
            app.SerialBaud.Items = {'4800', '9600', '115200'};
            app.SerialBaud.Value = '4800';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '停止位';
            app.SerialStopBit = uidropdown(app.LeftPanel);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '校验位';
            app.SerialCheckBit = uidropdown(app.LeftPanel);
            app.SerialCheckBit.Items = {'None', 'odd', 'Even'};
            app.SerialCheckBit.Value = 'None';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = '串口配置';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'关闭', '打开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Switch_value, true);
            app.Switch.Value = '关闭';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '声律魔方';
            app.Button = uibutton(app.CenterPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @video_in, true);
            app.Button.Text = '加载原始音频文件';
            app.TextArea = uitextarea(app.CenterPanel);
            app.FIRPanel = uipanel(app.CenterPanel);
            app.FIRPanel.Title = '音频滤波处理/FIR滤波器参数设置';
            app.HzLabel_2 = uilabel(app.FIRPanel);
            app.HzLabel_2.Text = '采样频率/Hz';
            app.freq_Fs = uieditfield(app.FIRPanel, 'numeric');
            app.freq_Fs.ValueChangedFcn = createCallbackFcn(app, @sample_freq, true);
            app.HzLabel_7 = uilabel(app.FIRPanel);
            app.HzLabel_7.Text = '截止频率/Hz';
            app.fir_fc = uieditfield(app.FIRPanel, 'numeric');
            app.fir_fc.ValueChangedFcn = createCallbackFcn(app, @filter_fc, true);
            app.fir_ctrl = uibutton(app.FIRPanel, 'push');
            app.fir_ctrl.ButtonPushedFcn = createCallbackFcn(app, @fir_data_ctrl, true);
            app.fir_ctrl.Text = '确定';
            app.Button_6 = uibutton(app.FIRPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @view_out, true);
            app.Button_6.Text = '开始播放';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.down = uitab(app.TabGroup);
            app.down.Title = '音频降频';
            app.HzLabel_9 = uilabel(app.down);
            app.HzLabel_9.Text = '目标采样率/Hz';
            app.down_sampling = uieditfield(app.down, 'numeric');
            app.HzLabel_8 = uilabel(app.down);
            app.HzLabel_8.Text = '当前采样率/Hz';
            app.HzEditField_8 = uieditfield(app.down, 'numeric');
            app.Button_5 = uibutton(app.down, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @down_sample_fs, true);
            app.Button_5.Text = '确定';
            app.Button_7 = uibutton(app.CenterPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @stop_out_music, true);
            app.Button_7.Text = '停止播放';
            app.Button_8 = uibutton(app.CenterPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @video_test_out, true);
            app.Button_8.Text = '播放测试音频';
            app.Panel = uipanel(app.CenterPanel);
            app.Panel.Title = '回声混响';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = '延迟长度';
            app.delay_slider = uislider(app.Panel);
            app.delay_slider.Limits = [5000 20000];
            app.delay_slider.Value = 10000;
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '增益系数';
            app.sub_slider = uislider(app.Panel);
            app.sub_slider.Limits = [0 1];
            app.sub_slider.Value = 0.05;
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @mixed_video, true);
            app.Button_9.Text = '确定';
            app.TabGroup_2 = uitabgroup(app.CenterPanel);
            app.down_2 = uitab(app.TabGroup_2);
            app.down_2.Title = '小房间混响';
            app.Button_10 = uibutton(app.down_2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @large_room_judge, true);
            app.Button_10.Text = '确定';
            app.Label_14 = uilabel(app.down_2);
            app.Label_14.Text = '反馈增益系数';
            app.little_silder = uislider(app.down_2);
            app.little_silder.Limits = [0 4];
            app.little_silder.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.little_silder.Value = 1;
            app.Tab = uitab(app.TabGroup_2);
            app.Tab.Title = '中房间混响';
            app.Slider_2Label = uilabel(app.Tab);
            app.Slider_2Label.Text = '反馈增益系数';
            app.Slider_mindum = uislider(app.Tab);
            app.Slider_mindum.Limits = [0 4];
            app.Slider_mindum.ValueChangingFcn = createCallbackFcn(app, @show_mindum, true);
            app.Slider_mindum.MinorTicks = [0 0.4 0.8 1.2 1.6];
            app.Slider_mindum.Value = 1;
            app.Button_11 = uibutton(app.Tab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @mindum_room, true);
            app.Button_11.Text = '确定';
            app.Tab_2 = uitab(app.TabGroup_2);
            app.Tab_2.Title = '大房间混响';
            app.Button_12 = uibutton(app.Tab_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @large_room, true);
            app.Button_12.Text = '确定';
            app.Slider_3Label = uilabel(app.Tab_2);
            app.Slider_3Label.Text = '反馈增益系数';
            app.large_slider = uislider(app.Tab_2);
            app.large_slider.Limits = [0 3];
            app.large_slider.MinorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.large_slider.Value = 1;
            app.dsp_loveLabel = uilabel(app.CenterPanel);
            app.dsp_loveLabel.Text = 'dsp_love';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '频域波形')
            xlabel(app.UIAxes, '归一化频率')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video_end

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end